classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        TabGroup           matlab.ui.container.TabGroup
        NiryoTab           matlab.ui.container.Tab
        JOINT6Knob_2       matlab.ui.control.Knob
        JOINT6Knob_2Label  matlab.ui.control.Label
        JOINT5Knob_2       matlab.ui.control.Knob
        JOINT5Knob_2Label  matlab.ui.control.Label
        JOINT4Knob_2       matlab.ui.control.Knob
        JOINT4Knob_2Label  matlab.ui.control.Label
        JOINT3Knob_2       matlab.ui.control.Knob
        JOINT3Knob_2Label  matlab.ui.control.Label
        JOINT2Knob_2       matlab.ui.control.Knob
        JOINT2Knob_2Label  matlab.ui.control.Label
        JOINT1Knob_2       matlab.ui.control.Knob
        JOINT1Knob_2Label  matlab.ui.control.Label
        UR3Tab             matlab.ui.container.Tab
        JOINT6Knob         matlab.ui.control.Knob
        JOINT6KnobLabel    matlab.ui.control.Label
        JOINT5Knob         matlab.ui.control.Knob
        JOINT5KnobLabel    matlab.ui.control.Label
        JOINT4Knob         matlab.ui.control.Knob
        JOINT4KnobLabel    matlab.ui.control.Label
        JOINT3Knob         matlab.ui.control.Knob
        JOINT3KnobLabel    matlab.ui.control.Label
        JOINT2Knob         matlab.ui.control.Knob
        JOINT2KnobLabel    matlab.ui.control.Label
        JOINT1Knob         matlab.ui.control.Knob
        JOINT1KnobLabel    matlab.ui.control.Label
        StartButton        matlab.ui.control.Button
        RightPanel         matlab.ui.container.Panel
        ESTOPSwitch        matlab.ui.control.ToggleSwitch
        ESTOPSwitchLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        myNiryoOne;
        myNiryoOneLink1 = 0;
        myNiryoOneLink2 = 0;
        myNiryoOneLink3 = 0;
        myNiryoOneLink4 = 0;
        myNiryoOneLink5 = 0;
        myNiryoOneLink6 = 0;

        myUR3;
        myUR3Link1 = 0;
        myUR3Link2 = -pi/2;
        myUR3Link3 = 0;
        myUR3Link4 = 0;
        myUR3Link5 = -pi/2;
        myUR3Link6 = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clf;            
            environment();

            % Set up Robot 1
            app.myNiryoOne = niryoOne(transl(0.54, -0.01, 0));    
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);  

            % Set up Robot 2
            app.myUR3 = UR3(transl(1.02, -0.01, 0));                                            
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]); 

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
               
        end

        % Value changing function: JOINT1Knob
        function JOINT1KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link1 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT2Knob
        function JOINT2KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link2 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT3Knob
        function JOINT3KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link3 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT4Knob
        function JOINT4KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link4 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT5Knob
        function JOINT5KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link5 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT6Knob
        function JOINT6KnobValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myUR3Link6 = deg2rad(changingJJointValue);
            app.myUR3.model.animate([app.myUR3Link1,app.myUR3Link2,app.myUR3Link3,app.myUR3Link4,app.myUR3Link5,app.myUR3Link6]);
        end

        % Value changing function: JOINT1Knob_2
        function JOINT1Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink1 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT2Knob_2
        function JOINT2Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink2 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT3Knob_2
        function JOINT3Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink3 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT4Knob_2
        function JOINT4Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink4 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT5Knob_2
        function JOINT5Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink5 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Value changing function: JOINT6Knob_2
        function JOINT6Knob_2ValueChanging(app, event)
            changingJJointValue = event.Value;
            app.myNiryoOneLink6 = deg2rad(changingJJointValue);
            app.myNiryoOne.model.animate([app.myNiryoOneLink1,app.myNiryoOneLink2,app.myNiryoOneLink3,app.myNiryoOneLink4,app.myNiryoOneLink5,app.myNiryoOneLink6]);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {366, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.NiryoTab = uitab(app.TabGroup);
            app.NiryoTab.Title = 'Niryo';
            app.JOINT1Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT1Knob_2Label.Text = 'JOINT 1';
            app.JOINT1Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT1Knob_2.Limits = [-175 175];
            app.JOINT1Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT1Knob_2ValueChanging, true);
            app.JOINT2Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT2Knob_2Label.Text = 'JOINT 2';
            app.JOINT2Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT2Knob_2.Limits = [-90 36.7];
            app.JOINT2Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT2Knob_2ValueChanging, true);
            app.JOINT3Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT3Knob_2Label.Text = 'JOINT 3';
            app.JOINT3Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT3Knob_2.Limits = [-80 90];
            app.JOINT3Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT3Knob_2ValueChanging, true);
            app.JOINT4Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT4Knob_2Label.Text = 'JOINT 4';
            app.JOINT4Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT4Knob_2.Limits = [-175 175];
            app.JOINT4Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT4Knob_2ValueChanging, true);
            app.JOINT5Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT5Knob_2Label.Text = 'JOINT 5';
            app.JOINT5Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT5Knob_2.Limits = [-100 110];
            app.JOINT5Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT5Knob_2ValueChanging, true);
            app.JOINT6Knob_2Label = uilabel(app.NiryoTab);
            app.JOINT6Knob_2Label.Text = 'JOINT 6';
            app.JOINT6Knob_2 = uiknob(app.NiryoTab, 'continuous');
            app.JOINT6Knob_2.Limits = [-147.5 147.5];
            app.JOINT6Knob_2.ValueChangingFcn = createCallbackFcn(app, @JOINT6Knob_2ValueChanging, true);
            app.UR3Tab = uitab(app.TabGroup);
            app.UR3Tab.Title = 'UR3';
            app.JOINT1KnobLabel = uilabel(app.UR3Tab);
            app.JOINT1KnobLabel.Text = 'JOINT 1';
            app.JOINT1Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT1Knob.Limits = [-360 360];
            app.JOINT1Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT1KnobValueChanging, true);
            app.JOINT2KnobLabel = uilabel(app.UR3Tab);
            app.JOINT2KnobLabel.Text = 'JOINT 2';
            app.JOINT2Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT2Knob.Limits = [-360 360];
            app.JOINT2Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT2KnobValueChanging, true);
            app.JOINT3KnobLabel = uilabel(app.UR3Tab);
            app.JOINT3KnobLabel.Text = 'JOINT 3';
            app.JOINT3Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT3Knob.Limits = [-360 360];
            app.JOINT3Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT3KnobValueChanging, true);
            app.JOINT4KnobLabel = uilabel(app.UR3Tab);
            app.JOINT4KnobLabel.Text = 'JOINT 4';
            app.JOINT4Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT4Knob.Limits = [-360 360];
            app.JOINT4Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT4KnobValueChanging, true);
            app.JOINT5KnobLabel = uilabel(app.UR3Tab);
            app.JOINT5KnobLabel.Text = 'JOINT 5';
            app.JOINT5Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT5Knob.Limits = [-360 360];
            app.JOINT5Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT5KnobValueChanging, true);
            app.JOINT6KnobLabel = uilabel(app.UR3Tab);
            app.JOINT6KnobLabel.Text = 'JOINT 6';
            app.JOINT6Knob = uiknob(app.UR3Tab, 'continuous');
            app.JOINT6Knob.Limits = [-360 360];
            app.JOINT6Knob.ValueChangingFcn = createCallbackFcn(app, @JOINT6KnobValueChanging, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.ESTOPSwitchLabel = uilabel(app.RightPanel);
            app.ESTOPSwitchLabel.Text = 'ESTOP';
            app.ESTOPSwitch = uiswitch(app.RightPanel, 'toggle');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end